classdef run_AVOCODO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        hand_editing                  matlab.ui.Figure
        CodingPanel                   matlab.ui.container.Panel
        markerLabel                   matlab.ui.control.Label
        txt_marker_type               matlab.ui.control.DropDown
        but_add_markers               matlab.ui.control.Button
        txt_instruction_6             matlab.ui.control.Label
        aLabel                        matlab.ui.control.Label
        but_drop                      matlab.ui.control.Button
        ExportingPanel                matlab.ui.container.Panel
        txt_script_remove_events      matlab.ui.control.EditField
        EditFieldLabel                matlab.ui.control.Label
        but_export_additional         matlab.ui.control.Button
        but_browse_script             matlab.ui.control.Button
        txt_instruction_9             matlab.ui.control.Label
        but_export                    matlab.ui.control.Button
        PlottingPanel                 matlab.ui.container.Panel
        txt_instruction_10            matlab.ui.control.Label
        txt_ERP_window                matlab.ui.control.EditField
        WindowLabel                   matlab.ui.control.Label
        txt_ERP_events                matlab.ui.control.EditField
        ERPEventsEditFieldLabel       matlab.ui.control.Label
        but_plot_ERP                  matlab.ui.control.Button
        but_plot_PSD                  matlab.ui.control.Button
        but_plot_EEG_raw              matlab.ui.control.Button
        but_plot_EEG_filtered         matlab.ui.control.Button
        MarkersPanel                  matlab.ui.container.Panel
        but_remove_selected           matlab.ui.control.Button
        txt_instruction_8             matlab.ui.control.Label
        txt_instruction_7             matlab.ui.control.Label
        but_redo                      matlab.ui.control.Button
        table_markers                 matlab.ui.control.Table
        but_undo                      matlab.ui.control.Button
        TableofaddedmarkersLabel      matlab.ui.control.Label
        VideoPlayandSpectrogramPanel  matlab.ui.container.Panel
        number_slider                 matlab.ui.control.NumericEditField
        secondsLabel_2                matlab.ui.control.Label
        spin_speed                    matlab.ui.control.Spinner
        PlaybackspeedSpinnerLabel     matlab.ui.control.Label
        but_pause                     matlab.ui.control.Button
        but_play                      matlab.ui.control.Button
        txt_instruction_5             matlab.ui.control.Label
        but_forward_more              matlab.ui.control.Button
        but_forward                   matlab.ui.control.Button
        but_back                      matlab.ui.control.Button
        but_back_more                 matlab.ui.control.Button
        slide_video                   matlab.ui.control.Slider
        txt_video_time                matlab.ui.control.Label
        CurrentvideotimeLabel         matlab.ui.control.Label
        number_window_width           matlab.ui.control.NumericEditField
        WindowwidthLabel              matlab.ui.control.Label
        secondsLabel_3                matlab.ui.control.Label
        axis_spectrogram              matlab.ui.control.UIAxes
        LoadEEGandVideoPanel          matlab.ui.container.Panel
        but_show_events               matlab.ui.control.Button
        inthelistLabel                matlab.ui.control.Label
        but_sync_video                matlab.ui.control.Button
        num_video_event_index         matlab.ui.control.NumericEditField
        txt_video_sync_event_index    matlab.ui.control.Label
        txt_video_sync_event          matlab.ui.control.Label
        txt_video_event               matlab.ui.control.EditField
        list_eeg_files                matlab.ui.control.ListBox
        FilesinpathListBoxLabel       matlab.ui.control.Label
        txt_instruction_2             matlab.ui.control.Label
        txt_instruction_1             matlab.ui.control.Label
        drop_number_videos            matlab.ui.control.DropDown
        ChoosevideoLabel              matlab.ui.control.Label
        txt_instruction_4             matlab.ui.control.Label
        lamp_load_video               matlab.ui.control.Lamp
        VideosloadedLabel             matlab.ui.control.Label
        lamp_load_eeg                 matlab.ui.control.Lamp
        EEGloadedLampLabel            matlab.ui.control.Label
        txt_instruction_3             matlab.ui.control.Label
        but_load_eeg                  matlab.ui.control.Button
        txt_path_data                 matlab.ui.control.EditField
        DatafolderLabel               matlab.ui.control.Label
        but_path_data                 matlab.ui.control.Button
        switch_instructions           matlab.ui.control.Switch
        InstructionsSwitchLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            func_gui_initializing(app);
        end

        % Button pushed function: but_path_data
        function but_path_dataButtonPushed(app, event)
            % Prompt to browse file path
            func_update_file_list(app,1)
        end

        % Button pushed function: but_load_eeg
        function but_load_eegButtonPushed(app, event)
            % Reset Workspace
            flag_continue = func_reset_workspace(app);
            
            if flag_continue
                % Load EEG
                func_load_eeg(app);
    
                % Load videos and audios
                func_load_videos(app);
            end
        end

        % Close request function: hand_editing
        function hand_editingCloseRequest(app, event)
            func_gui_closing(app);
        end

        % Callback function
        function but_load_videoButtonPushed(app, event)
            % Load videos and audios
            func_load_videos(app);
            
        end

        % Callback function
        function but_path_eeglabButtonPushed(app, event)
            % Prompt to browse path
            path_eeglab = uigetdir(app.txt_path_eeglab.Value);
            app.txt_path_eeglab.Value = path_eeglab;%update file path
            
            % add EEGLAB path
            addpath(genpath(path_eeglab),'-END');
        end

        % Callback function
        function but_video_playButtonPushed(app, event)
            % Play video
            func_play_video(app);
        end

        % Value changing function: slide_video
        function slide_videoValueChanging(app, event)
            changingValue = event.Value;
            func_time_change(app,changingValue);
        end

        % Value changed function: slide_video
        function slide_videoValueChanged(app, event)
            value = app.slide_video.Value;
            func_time_change(app,value);
        end

        % Value changed function: drop_number_videos
        function drop_number_videosValueChanged(app, event)
            value = app.drop_number_videos.Value;
            func_choose_video(app,value);
        end

        % Value changed function: number_slider
        function number_sliderValueChanged(app, event)
            value = app.number_slider.Value;
            func_time_change(app,value);
        end

        % Callback function
        function but_pauseValueChanged(app, event)
            value = app.but_pause.Value;
            if value%play if True
                % Play video
                app.but_pause.Text = 'Pause';
                func_play_video(app);
            else
                app.but_pause.Text = 'Play';
            end
        end

        % Callback function
        function but_syncButtonPushed(app, event)
            vlc = getappdata(app.hand_editing,'vlc');
            time_vlc = vlc.Current.Position/1000000;%in seconds;
            time_gui = app.number_slider.Value;
            if abs(time_vlc-time_gui)>1%if video and gui time differs >1second
                func_time_change(app,time_vlc);
            end
        end

        % Callback function: not associated with a component
        function but_dropButtonPushed(app, event)
            % Drop event markers
            % Sync with video first
            vlc = getappdata(app.hand_editing,'vlc');
            if isempty(vlc.Current)%if video hasn't been played
                errordlg('No time has been selected. Play video first!!', 'Error');
                return;
            end

            time_vlc = vlc.Current.Position/1000000;%in seconds;
            time_gui = app.number_slider.Value;
            if abs(time_vlc-time_gui)>1%if video and gui time differs >1second
                func_time_change(app,time_vlc);
            end

            func_drop_marker(app);
        end

        % Button pushed function: but_undo
        function but_undoButtonPushed(app, event)
            % Remove last added marker
            func_undo_marker(app,0);
        end

        % Button pushed function: but_redo
        function but_redoButtonPushed(app, event)
            % Redo a marker deletion
            func_redo_marker(app);
        end

        % Button pushed function: but_export
        function but_exportButtonPushed(app, event)
            % Export EEG data
            func_export(app,0);%0 for no additional scripts
        end

        % Button pushed function: but_export_additional
        function but_export_additionalButtonPushed(app, event)
            func_export(app,1);%1 for additional scripts
        end

        % Value changed function: number_window_width
        function number_window_widthValueChanged(app, event)
            value = app.number_window_width.Value;
            time = app.number_slider.Value;
            % Update spectrogram
            window_width = value/2;%seconds
            xlim = [time-window_width,time+window_width];
            set(app.axis_spectrogram,'xlim',xlim);
        end

        % Button pushed function: but_back
        function but_backButtonPushed(app, event)
            time = app.number_slider.Value;
            time = max([time-0.1,0]);
            func_time_change(app,time);
        end

        % Button pushed function: but_back_more
        function but_back_moreButtonPushed(app, event)
            time = app.number_slider.Value;
            time = max([time-1,0]);
            func_time_change(app,time);
        end

        % Button pushed function: but_forward
        function but_forwardButtonPushed(app, event)
            time = app.number_slider.Value;
            time_max = app.slide_video.Limits(2);
            time = min([time+0.1,time_max]);
            func_time_change(app,time);
        end

        % Button pushed function: but_forward_more
        function but_forward_moreButtonPushed(app, event)
            time = app.number_slider.Value;
            time_max = app.slide_video.Limits(2);
            time = min([time+1,time_max]);
            func_time_change(app,time);
        end

        % Callback function
        function but_playValueChanged(app, event)
            func_play(app);
        end

        % Value changed function: switch_instructions
        function switch_instructionsValueChanged(app, event)
            value = app.switch_instructions.Value;
            func_show_instructions(app);
        end

        % Callback function
        function but_pauseValueChanged2(app, event)
            value = app.but_pause.Value;
            vlc = getappdata(app.hand_editing,'vlc');
            vlc.pause();
            time = vlc.Current.Position/1000000;%in seconds;
            func_time_change(app,time);
        end

        % Callback function
        function but_playValueChanged2(app, event)
            value = app.but_play.Value;
            func_play(app);
        end

        % Button pushed function: but_play
        function but_playPushed(app, event)
            func_play(app);
        end

        % Button pushed function: but_pause
        function but_pauseButtonPushed(app, event)
            vlc = getappdata(app.hand_editing,'vlc');
            vlc.pause();
            time = vlc.Current.Position/1000000;%in seconds;
            func_time_change(app,time);
        end

        % Value changed function: spin_speed
        function spin_speedValueChanged(app, event)
            value = app.spin_speed.Value;
            vlc = getappdata(app.hand_editing,'vlc');
            vlc.Rate = value;
        end

        % Callback function: not associated with a component
        function but_add_markersButtonPushed(app, event)
            func_add_marker_types(app);
        end

        % Button pushed function: but_browse_script
        function but_browse_scriptButtonPushed(app, event)
            % Prompt to browse path
            if isempty(app.txt_script_remove_events.Value)
                [file,location] = uigetfile(fullfile(ctfroot,'*.m'));
            else
                [file,location] = uigetfile(fullfile(app.txt_script_remove_events.Value,'*.m'));
            end
            %addpath(location);
            app.txt_script_remove_events.Value = fullfile(location,file);%update file path
        end

        % Selection changed function: table_markers
        function table_markersSelectionChanged(app, event)
            selection = app.table_markers.Selection;%[row,column]
            tab = app.table_markers;%table in gui
            tab_data = get(tab,'Data');
            lat = tab_data{selection(1),3};
            func_time_change(app,lat)
        end

        % Button pushed function: but_remove_selected
        function but_remove_selectedButtonPushed(app, event)
            selection = app.table_markers.Selection;%[row,column]
            func_undo_marker(app,selection(1));%remove the selected row
        end

        % Button pushed function: but_plot_EEG_filtered
        function but_plot_EEG_filteredButtonPushed(app, event)
            % Plot EEG at the current time in a pop up window
            func_plot_EEG(app,1);
        end

        % Button pushed function: but_plot_EEG_raw
        function but_plot_EEG_rawButtonPushed(app, event)
            func_plot_EEG(app,0);
        end

        % Button pushed function: but_plot_PSD
        function but_plot_PSDButtonPushed(app, event)
            func_plot_PSD(app);
        end

        % Button pushed function: but_plot_ERP
        function but_plot_ERPButtonPushed(app, event)
            txt_erp_events = app.txt_ERP_events.Value;
            txt_erp_window = app.txt_ERP_window.Value;
            func_plot_ERP(app,txt_erp_events,txt_erp_window);
        end

        % Button pushed function: but_sync_video
        function but_sync_videoButtonPushed(app, event)
            EEG = getappdata(app.hand_editing,'EEG');
            n_videos = getappdata(app.hand_editing,'n_videos');
            selected_events = getappdata(app.hand_editing,'selected_events');

            func_select_event_to_sync(app,EEG,n_videos,selected_events);
            func_choose_video(app,'1');

            func_update_table_after_resync(app);
        end

        % Button pushed function: but_add_markers
        function but_add_markersButtonPushed2(app, event)
            func_add_marker_types(app);
        end

        % Button pushed function: but_drop
        function but_dropButtonPushed2(app, event)
            % Drop event markers
            % Sync with video first
            vlc = getappdata(app.hand_editing,'vlc');
            if isempty(vlc.Current)%if video hasn't been played
                errordlg('No time has been selected. Play video first!!', 'Error');
                return;
            end

            time_vlc = vlc.Current.Position/1000000;%in seconds;
            time_gui = app.number_slider.Value;
            if abs(time_vlc-time_gui)>1%if video and gui time differs >1second
                func_time_change(app,time_vlc);
            end

            func_drop_marker(app);
        end

        % Button pushed function: but_show_events
        function but_show_eventsButtonPushed(app, event)
            EEG = getappdata(app.hand_editing,'EEG');
            func_show_eeg_events(app, EEG);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.hand_editing = uifigure('Visible', 'off');
            app.hand_editing.Name = 'AVOCODO';
            app.hand_editing.CloseRequestFcn = createCallbackFcn(app, @hand_editingCloseRequest, true);
            app.InstructionsSwitchLabel = uilabel(app.hand_editing);
            app.InstructionsSwitchLabel.Text = 'Instructions';
            app.switch_instructions = uiswitch(app.hand_editing, 'slider');
            app.switch_instructions.ValueChangedFcn = createCallbackFcn(app, @switch_instructionsValueChanged, true);
            app.LoadEEGandVideoPanel = uipanel(app.hand_editing);
            app.LoadEEGandVideoPanel.Title = 'Load EEG and Video';
            app.but_path_data = uibutton(app.LoadEEGandVideoPanel, 'push');
            app.but_path_data.ButtonPushedFcn = createCallbackFcn(app, @but_path_dataButtonPushed, true);
            app.but_path_data.Text = 'Browse Data';
            app.DatafolderLabel = uilabel(app.LoadEEGandVideoPanel);
            app.DatafolderLabel.Text = 'Data folder';
            app.txt_path_data = uieditfield(app.LoadEEGandVideoPanel, 'text');
            app.but_load_eeg = uibutton(app.LoadEEGandVideoPanel, 'push');
            app.but_load_eeg.ButtonPushedFcn = createCallbackFcn(app, @but_load_eegButtonPushed, true);
            app.but_load_eeg.Text = 'Load EEG and videos';
            app.txt_instruction_3 = uilabel(app.LoadEEGandVideoPanel);
            app.txt_instruction_3.Visible = 'off';
            app.txt_instruction_3.Text = '^ 3. Load EEG and videos';
            app.EEGloadedLampLabel = uilabel(app.LoadEEGandVideoPanel);
            app.EEGloadedLampLabel.Text = 'EEG loaded?';
            app.lamp_load_eeg = uilamp(app.LoadEEGandVideoPanel);
            app.VideosloadedLabel = uilabel(app.LoadEEGandVideoPanel);
            app.VideosloadedLabel.Text = 'Videos loaded?';
            app.lamp_load_video = uilamp(app.LoadEEGandVideoPanel);
            app.txt_instruction_4 = uilabel(app.LoadEEGandVideoPanel);
            app.txt_instruction_4.Visible = 'off';
            app.txt_instruction_4.Text = '^ 4. Choose a video (if >1 files)';
            app.ChoosevideoLabel = uilabel(app.LoadEEGandVideoPanel);
            app.ChoosevideoLabel.Text = 'Choose video';
            app.drop_number_videos = uidropdown(app.LoadEEGandVideoPanel);
            app.drop_number_videos.Items = {'1'};
            app.drop_number_videos.ValueChangedFcn = createCallbackFcn(app, @drop_number_videosValueChanged, true);
            app.drop_number_videos.Value = '1';
            app.txt_instruction_1 = uilabel(app.LoadEEGandVideoPanel);
            app.txt_instruction_1.Visible = 'off';
            app.txt_instruction_1.Text = 'V 1. Specify path to data';
            app.txt_instruction_2 = uilabel(app.LoadEEGandVideoPanel);
            app.txt_instruction_2.Visible = 'off';
            app.txt_instruction_2.Text = '2. Select an EEG file ->';
            app.FilesinpathListBoxLabel = uilabel(app.LoadEEGandVideoPanel);
            app.FilesinpathListBoxLabel.Text = {'Files'; 'in'; 'path'};
            app.list_eeg_files = uilistbox(app.LoadEEGandVideoPanel);
            app.list_eeg_files.Items = {'No files selected'};
            app.list_eeg_files.Value = 'No files selected';
            app.txt_video_event = uieditfield(app.LoadEEGandVideoPanel, 'text');
            app.txt_video_event.Editable = 'off';
            app.txt_video_event.Value = 'VBeg';
            app.txt_video_sync_event = uilabel(app.LoadEEGandVideoPanel);
            app.txt_video_sync_event.Text = 'Video synced to this event:';
            app.txt_video_sync_event_index = uilabel(app.LoadEEGandVideoPanel);
            app.txt_video_sync_event_index.Text = ', indexed';
            app.num_video_event_index = uieditfield(app.LoadEEGandVideoPanel, 'numeric');
            app.num_video_event_index.Editable = 'off';
            app.num_video_event_index.Value = 1;
            app.but_sync_video = uibutton(app.LoadEEGandVideoPanel, 'push');
            app.but_sync_video.ButtonPushedFcn = createCallbackFcn(app, @but_sync_videoButtonPushed, true);
            app.but_sync_video.Text = 'Re-select sync events';
            app.inthelistLabel = uilabel(app.LoadEEGandVideoPanel);
            app.inthelistLabel.Text = 'in the list';
            app.but_show_events = uibutton(app.LoadEEGandVideoPanel, 'push');
            app.but_show_events.ButtonPushedFcn = createCallbackFcn(app, @but_show_eventsButtonPushed, true);
            app.but_show_events.Text = 'Show all EEG events';
            app.VideoPlayandSpectrogramPanel = uipanel(app.hand_editing);
            app.VideoPlayandSpectrogramPanel.Title = 'Video Play and Spectrogram';
            app.axis_spectrogram = uiaxes(app.VideoPlayandSpectrogramPanel);
            xlabel(app.axis_spectrogram, 'Time (second)')
            ylabel(app.axis_spectrogram, 'Frequency (Hz)')
            zlabel(app.axis_spectrogram, 'Z')
            app.secondsLabel_3 = uilabel(app.VideoPlayandSpectrogramPanel);
            app.secondsLabel_3.Text = 'seconds';
            app.WindowwidthLabel = uilabel(app.VideoPlayandSpectrogramPanel);
            app.WindowwidthLabel.Text = 'Window width';
            app.number_window_width = uieditfield(app.VideoPlayandSpectrogramPanel, 'numeric');
            app.number_window_width.Limits = [0 Inf];
            app.number_window_width.ValueChangedFcn = createCallbackFcn(app, @number_window_widthValueChanged, true);
            app.number_window_width.Value = 10;
            app.CurrentvideotimeLabel = uilabel(app.VideoPlayandSpectrogramPanel);
            app.CurrentvideotimeLabel.Text = 'Current video time:';
            app.txt_video_time = uilabel(app.VideoPlayandSpectrogramPanel);
            app.txt_video_time.Text = '00:00';
            app.slide_video = uislider(app.VideoPlayandSpectrogramPanel);
            app.slide_video.MajorTicks = [0 50 100];
            app.slide_video.ValueChangedFcn = createCallbackFcn(app, @slide_videoValueChanged, true);
            app.slide_video.ValueChangingFcn = createCallbackFcn(app, @slide_videoValueChanging, true);
            app.but_back_more = uibutton(app.VideoPlayandSpectrogramPanel, 'push');
            app.but_back_more.ButtonPushedFcn = createCallbackFcn(app, @but_back_moreButtonPushed, true);
            app.but_back_more.Text = '<<';
            app.but_back = uibutton(app.VideoPlayandSpectrogramPanel, 'push');
            app.but_back.ButtonPushedFcn = createCallbackFcn(app, @but_backButtonPushed, true);
            app.but_back.Text = '<';
            app.but_forward = uibutton(app.VideoPlayandSpectrogramPanel, 'push');
            app.but_forward.ButtonPushedFcn = createCallbackFcn(app, @but_forwardButtonPushed, true);
            app.but_forward.Text = '>';
            app.but_forward_more = uibutton(app.VideoPlayandSpectrogramPanel, 'push');
            app.but_forward_more.ButtonPushedFcn = createCallbackFcn(app, @but_forward_moreButtonPushed, true);
            app.but_forward_more.Text = '>>';
            app.txt_instruction_5 = uilabel(app.VideoPlayandSpectrogramPanel);
            app.txt_instruction_5.Visible = 'off';
            app.txt_instruction_5.Text = '5. Play video using the button below. (Don''t control playback on VLC.) Use slider and arrows to go forward/backward.';
            app.but_play = uibutton(app.VideoPlayandSpectrogramPanel, 'push');
            app.but_play.ButtonPushedFcn = createCallbackFcn(app, @but_playPushed, true);
            app.but_play.Text = 'Play';
            app.but_pause = uibutton(app.VideoPlayandSpectrogramPanel, 'push');
            app.but_pause.ButtonPushedFcn = createCallbackFcn(app, @but_pauseButtonPushed, true);
            app.but_pause.Text = 'Pause';
            app.PlaybackspeedSpinnerLabel = uilabel(app.VideoPlayandSpectrogramPanel);
            app.PlaybackspeedSpinnerLabel.Text = {'Playback'; 'speed'};
            app.spin_speed = uispinner(app.VideoPlayandSpectrogramPanel);
            app.spin_speed.Step = 0.2;
            app.spin_speed.Limits = [0.6 2];
            app.spin_speed.ValueChangedFcn = createCallbackFcn(app, @spin_speedValueChanged, true);
            app.spin_speed.Value = 1;
            app.secondsLabel_2 = uilabel(app.VideoPlayandSpectrogramPanel);
            app.secondsLabel_2.Text = 'seconds';
            app.number_slider = uieditfield(app.VideoPlayandSpectrogramPanel, 'numeric');
            app.number_slider.ValueChangedFcn = createCallbackFcn(app, @number_sliderValueChanged, true);
            app.MarkersPanel = uipanel(app.hand_editing);
            app.MarkersPanel.Title = 'Markers';
            app.TableofaddedmarkersLabel = uilabel(app.MarkersPanel);
            app.TableofaddedmarkersLabel.Text = 'Table of added markers';
            app.but_undo = uibutton(app.MarkersPanel, 'push');
            app.but_undo.ButtonPushedFcn = createCallbackFcn(app, @but_undoButtonPushed, true);
            app.but_undo.Text = 'Remove last';
            app.table_markers = uitable(app.MarkersPanel);
            app.table_markers.ColumnName = {'Type'; 'Latency in EEG'; 'Latency in video'; '# video'};
            app.table_markers.RowName = {};
            app.table_markers.SelectionChangedFcn = createCallbackFcn(app, @table_markersSelectionChanged, true);
            app.but_redo = uibutton(app.MarkersPanel, 'push');
            app.but_redo.ButtonPushedFcn = createCallbackFcn(app, @but_redoButtonPushed, true);
            app.but_redo.Text = 'Undo';
            app.txt_instruction_7 = uilabel(app.MarkersPanel);
            app.txt_instruction_7.Visible = 'off';
            app.txt_instruction_7.Text = 'V 8. You can delete/undo if a marker is dropped at a wrong time.';
            app.txt_instruction_8 = uilabel(app.MarkersPanel);
            app.txt_instruction_8.Visible = 'off';
            app.txt_instruction_8.Text = 'V 7. The markers you dropped are shown here.';
            app.but_remove_selected = uibutton(app.MarkersPanel, 'push');
            app.but_remove_selected.ButtonPushedFcn = createCallbackFcn(app, @but_remove_selectedButtonPushed, true);
            app.but_remove_selected.Text = 'Remove selected';
            app.PlottingPanel = uipanel(app.hand_editing);
            app.PlottingPanel.Title = 'Plotting';
            app.but_plot_EEG_filtered = uibutton(app.PlottingPanel, 'push');
            app.but_plot_EEG_filtered.ButtonPushedFcn = createCallbackFcn(app, @but_plot_EEG_filteredButtonPushed, true);
            app.but_plot_EEG_filtered.Text = 'Plot filtered EEG time course';
            app.but_plot_EEG_raw = uibutton(app.PlottingPanel, 'push');
            app.but_plot_EEG_raw.ButtonPushedFcn = createCallbackFcn(app, @but_plot_EEG_rawButtonPushed, true);
            app.but_plot_EEG_raw.Text = 'Plot Raw EEG time course';
            app.but_plot_PSD = uibutton(app.PlottingPanel, 'push');
            app.but_plot_PSD.ButtonPushedFcn = createCallbackFcn(app, @but_plot_PSDButtonPushed, true);
            app.but_plot_PSD.Text = 'Plot PSD';
            app.but_plot_ERP = uibutton(app.PlottingPanel, 'push');
            app.but_plot_ERP.ButtonPushedFcn = createCallbackFcn(app, @but_plot_ERPButtonPushed, true);
            app.but_plot_ERP.Text = 'Plot ERP';
            app.ERPEventsEditFieldLabel = uilabel(app.PlottingPanel);
            app.ERPEventsEditFieldLabel.Text = {'ERP'; 'Events'};
            app.txt_ERP_events = uieditfield(app.PlottingPanel, 'text');
            app.txt_ERP_events.Value = '{''stm1'',''stm2''}';
            app.WindowLabel = uilabel(app.PlottingPanel);
            app.WindowLabel.Text = 'Window';
            app.txt_ERP_window = uieditfield(app.PlottingPanel, 'text');
            app.txt_ERP_window.Value = '[-0.1,0.5]';
            app.txt_instruction_10 = uilabel(app.PlottingPanel);
            app.txt_instruction_10.Visible = 'off';
            app.txt_instruction_10.Text = 'v 10. Plot EEG if interested. Totally optional.';
            app.ExportingPanel = uipanel(app.hand_editing);
            app.ExportingPanel.Title = 'Exporting';
            app.but_export = uibutton(app.ExportingPanel, 'push');
            app.but_export.ButtonPushedFcn = createCallbackFcn(app, @but_exportButtonPushed, true);
            app.but_export.Text = 'Export!';
            app.txt_instruction_9 = uilabel(app.ExportingPanel);
            app.txt_instruction_9.Visible = 'off';
            app.txt_instruction_9.Text = {'<= 9. Export EEG '; 'once you are done! '; 'Or with an additional '; 'script =>'};
            app.but_browse_script = uibutton(app.ExportingPanel, 'push');
            app.but_browse_script.ButtonPushedFcn = createCallbackFcn(app, @but_browse_scriptButtonPushed, true);
            app.but_browse_script.Text = 'Browse Script';
            app.but_export_additional = uibutton(app.ExportingPanel, 'push');
            app.but_export_additional.ButtonPushedFcn = createCallbackFcn(app, @but_export_additionalButtonPushed, true);
            app.but_export_additional.Text = {'Export with '; 'an additional '; 'script!'};
            app.EditFieldLabel = uilabel(app.ExportingPanel);
            app.EditFieldLabel.Text = 'Use a .m script to modify events based on specific rules';
            app.txt_script_remove_events = uieditfield(app.ExportingPanel, 'text');
            app.CodingPanel = uipanel(app.hand_editing);
            app.CodingPanel.Title = 'Coding';
            app.but_drop = uibutton(app.CodingPanel, 'push');
            app.but_drop.ButtonPushedFcn = createCallbackFcn(app, @but_dropButtonPushed2, true);
            app.but_drop.Text = 'Drop';
            app.aLabel = uilabel(app.CodingPanel);
            app.aLabel.Text = 'a';
            app.txt_instruction_6 = uilabel(app.CodingPanel);
            app.txt_instruction_6.Visible = 'off';
            app.txt_instruction_6.Text = '< 6. Drop markers at time instances. Setup markers as needed.';
            app.but_add_markers = uibutton(app.CodingPanel, 'push');
            app.but_add_markers.ButtonPushedFcn = createCallbackFcn(app, @but_add_markersButtonPushed2, true);
            app.but_add_markers.Text = 'Setup markers';
            app.txt_marker_type = uidropdown(app.CodingPanel);
            app.txt_marker_type.Items = {};
            app.txt_marker_type.Value = {};
            app.markerLabel = uilabel(app.CodingPanel);
            app.markerLabel.Text = 'marker';
            % Show the figure after all components are created
            app.hand_editing.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_AVOCODO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.hand_editing)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.hand_editing)
        end
    end
end